classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        filenameEditFieldLabel     matlab.ui.control.Label
        filenameEditField          matlab.ui.control.EditField
        samplerateEditFieldLabel   matlab.ui.control.Label
        samplerateEditField        matlab.ui.control.NumericEditField
        filterButtonGroup          matlab.ui.container.ButtonGroup
        IIRButton                  matlab.ui.control.RadioButton
        FIRButton                  matlab.ui.control.RadioButton
        HzLabel                    matlab.ui.control.Label
        gain1                      matlab.ui.control.Spinner
        HzSpinner_2Label           matlab.ui.control.Label
        gain2                      matlab.ui.control.Spinner
        HzSpinner_3Label           matlab.ui.control.Label
        gain3                      matlab.ui.control.Spinner
        HzSpinner_4Label           matlab.ui.control.Label
        gain4                      matlab.ui.control.Spinner
        Hz3KHzLabel                matlab.ui.control.Label
        gain5                      matlab.ui.control.Spinner
        KHzLabel                   matlab.ui.control.Label
        gain6                      matlab.ui.control.Spinner
        KHzSpinner_2Label          matlab.ui.control.Label
        gain7                      matlab.ui.control.Spinner
        KHzSpinner_3Label          matlab.ui.control.Label
        gain8                      matlab.ui.control.Spinner
        KHzSpinner_4Label          matlab.ui.control.Label
        gain9                      matlab.ui.control.Spinner
        dBLabel                    matlab.ui.control.Label
        dBLabel_2                  matlab.ui.control.Label
        dBLabel_3                  matlab.ui.control.Label
        dBLabel_4                  matlab.ui.control.Label
        dBLabel_5                  matlab.ui.control.Label
        dBLabel_6                  matlab.ui.control.Label
        dBLabel_7                  matlab.ui.control.Label
        dBLabel_8                  matlab.ui.control.Label
        dBLabel_9                  matlab.ui.control.Label
        inputPlot                  matlab.ui.control.UIAxes
        outputPlot                 matlab.ui.control.UIAxes
        domainButtonGroup          matlab.ui.container.ButtonGroup
        TimeButton                 matlab.ui.control.RadioButton
        FrequencyButton            matlab.ui.control.RadioButton
        RUNButton                  matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        FrequencyButtonGroup       matlab.ui.container.ButtonGroup
        magButton                  matlab.ui.control.RadioButton
        phaseButton                matlab.ui.control.RadioButton
        bothButton                 matlab.ui.control.RadioButton
        FiltertoplotDropDownLabel  matlab.ui.control.Label
        FiltertoplotDropDown       matlab.ui.control.DropDown
        bandPlot                   matlab.ui.control.UIAxes
        ShowdetailsButton          matlab.ui.control.Button
        PlotButton                 matlab.ui.control.Button
    end


    properties (Access = public)
        y % sound vector
        orginal_freq 
        player %play sound
        y_final %final sound
        fs %sampling freq
        tx 
        y1
        y2
        y3
        y4
        y5
        y6
        y7
        y8
        y9
        a1
        a2
        a3
        a4
        a5
        a6
        a7
        a8
        a9
        b1
        b2
        b3
        b4
        b5
        b6
        b7
        b8
        b9
    end


    methods (Access = private)

        % Button pushed function: ShowdetailsButton
        function ShowdetailsButtonPushed(app, event)
                n = app.FiltertoplotDropDown.Value;
             switch n
                    case '0-170 Hz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b1,app.a1);
                        subplot(2,2,2);
                        stepz(app.b1,app.a1);
                        [z,p] = tf2zp(app.b1,app.a1);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b1,app.a1);
                    case '170-300 Hz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b2,app.a2);
                        subplot(2,2,2);
                        stepz(app.b2,app.a2);
                        [z,p] = tf2zp(app.b2,app.a2);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b2,app.a2);
                    case '300-610 Hz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b3,app.a3);
                        subplot(2,2,2);
                        stepz(app.b3,app.a3);
                        [z,p] = tf2zp(app.b3,app.a3);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b3,app.a3);
                    case '610-1005 Hz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b4,app.a4);
                        subplot(2,2,2);
                        stepz(app.b4,app.a4);
                        [z,p] = tf2zp(app.b4,app.a4);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b4,app.a4);
                    case '1005-3000 Hz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b5,app.a5);
                        subplot(2,2,2);
                        stepz(app.b5,app.a5);
                        [z,p] = tf2zp(app.b5,app.a5);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b5,app.a5);
                    case '3-6 KHz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b6,app.a6);
                        subplot(2,2,2);
                        stepz(app.b6,app.a6);
                        [z,p] = tf2zp(app.b6,app.a6);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b6,app.a6);
                    case '6-12 KHz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b7,app.a7);
                        subplot(2,2,2);
                        stepz(app.b7,app.a7);
                        [z,p] = tf2zp(app.b7,app.a7);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b7,app.a7);
                    case '12-14 KHz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b8,app.a8);
                        subplot(2,2,2);
                        stepz(app.b8,app.a8);
                        [z,p,k] = tf2zp(app.b8,app.a8);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b8,app.a8);
                    case '14-20 KHz'
                        figure;
                        subplot(2,2,1);
                        impz(app.b9,app.a9);
                        subplot(2,2,2);
                        stepz(app.b9,app.a9);
                        [z,p] = tf2zp(app.b9,app.a9);
                        subplot(2,2,3);
                        zplane(z,p);
                        figure;
                        freqz(app.b9,app.a9);
                        
              end
        end

        % Value changed function: filenameEditField
        function filePath(app, event)
            value = app.samplerateEditField.Value;
        end

        % Button pushed function: PlotButton
        function plotBand(app, event)
            n = app.FiltertoplotDropDown.Value;
            x = linspace( -app.fs/2 , app.fs/2, length(app.y_final));
            cla(app.bandPlot);
            if app.TimeButton.Value
                switch n
                    case '0-170 Hz'
                        plot(app.bandPlot,app.tx,app.y1);
                    case '170-300 Hz'
                        plot(app.bandPlot,app.tx,app.y2);
                    case '300-610 Hz'
                        plot(app.bandPlot,app.tx,app.y3);
                    case '610-1005 Hz'
                        plot(app.bandPlot,app.tx,app.y4);
                    case '1005-3000 Hz'
                        plot(app.bandPlot,app.tx,app.y5);
                    case '3-6 KHz'
                        plot(app.bandPlot,app.tx,app.y6);
                    case '6-12 KHz'
                        plot(app.bandPlot,app.tx,app.y5);
                    case '12-14 KHz'
                        plot(app.bandPlot,app.tx,app.y5);
                    case '14-20 KHz'
                        plot(app.bandPlot,app.tx,app.y5);
                        
                end
            else
                [h1,w1] = freqz(app.b1,app.a1);
                [h2,w2] = freqz(app.b2,app.a2);
                [h3,w3] = freqz(app.b3,app.a3);
                [h4,w4] = freqz(app.b4,app.a4);
                [h5,w5] = freqz(app.b5,app.a5);
                [h6,w6] = freqz(app.b6,app.a6);
                [h7,w7] = freqz(app.b7,app.a7);
                [h8,w8] = freqz(app.b8,app.a8);
                [h9,w9] = freqz(app.b9,app.a9);
                if app.magButton.Value
                  switch n
                    case '0-170 Hz'
                        plot(app.bandPlot,w1/pi,20*log10(abs(h1)));
                    case '170-300 Hz'
                        plot(app.bandPlot,w2/pi,20*log10(abs(h2)));
                    case '300-610 Hz'
                        plot(app.bandPlot,w3/pi,20*log10(abs(h3)));
                    case '610-1005 Hz'
                        plot(app.bandPlot,w4/pi,20*log10(abs(h4)));
                    case '1005-3000 Hz'
                        plot(app.bandPlot,w5/pi,20*log10(abs(h5)));
                    case '3-6 KHz'
                        plot(app.bandPlot,w6/pi,20*log10(abs(h6)));
                    case '6-12 KHz'
                        plot(app.bandPlot,w7/pi,20*log10(abs(h7)));
                    case '12-14 KHz'
                        plot(app.bandPlot,w8/pi,20*log10(abs(h8)));
                    case '14-20 KHz'
                        plot(app.bandPlot,w9/pi,20*log10(abs(h9)));
                  end
                  
                 elseif app.phaseButton.Value
                     switch n
                    case '0-170 Hz'
                        plot(app.bandPlot,w1/pi,angle(h1)*180/pi);
                    case '170-300 Hz'
                        plot(app.bandPlot,w2/pi,angle(h2)*180/pi);
                    case '300-610 Hz'
                        plot(app.bandPlot,w3/pi,angle(h3)*180/pi);
                    case '610-1005 Hz'
                        plot(app.bandPlot,w4/pi,angle(h4)*180/pi);
                    case '1005-3000 Hz'
                        plot(app.bandPlot,w5/pi,angle(h5)*180/pi);
                    case '3-6 KHz'
                        plot(app.bandPlot,w6/pi,angle(h6)*180/pi);
                    case '6-12 KHz'
                        plot(app.bandPlot,w7/pi,angle(h7)*180/pi);
                    case '12-14 KHz'
                        plot(app.bandPlot,w8/pi,angle(h8)*180/pi);
                    case '14-20 KHz'
                        plot(app.bandPlot,w9/pi,angle(h9)*180/pi);
                     end
                else
                    switch n
                    case '0-170 Hz'
                        plot(app.bandPlot,w1/pi,20*log10(abs(h1)),w1/pi,angle(h1)*180/pi);
                    case '170-300 Hz'
                       plot(app.bandPlot,w2/pi,20*log10(abs(h1)),w2/pi,angle(h2)*180/pi);
                    case '300-610 Hz'
                        plot(app.bandPlot,w3/pi,20*log10(abs(h1)),w3/pi,angle(h3)*180/pi);
                    case '610-1005 Hz'
                        plot(app.bandPlot,w4/pi,20*log10(abs(h1)),w4/pi,angle(h4)*180/pi);
                    case '1005-3000 Hz'
                        plot(app.bandPlot,w5/pi,20*log10(abs(h1)),w5/pi,angle(h5)*180/pi);
                    case '3-6 KHz'
                        plot(app.bandPlot,w6/pi,20*log10(abs(h1)),w6/pi,angle(h6)*180/pi);
                    case '6-12 KHz'
                        plot(app.bandPlot,w7/pi,20*log10(abs(h1)),w7/pi,angle(h7)*180/pi);
                    case '12-14 KHz'
                        plot(app.bandPlot,w8/pi,20*log10(abs(h1)),w8/pi,angle(h8)*180/pi);
                    case '14-20 KHz'
                        plot(app.bandPlot,w9/pi,20*log10(abs(h1)),w9/pi,angle(h9)*180/pi);   
                    end
                end      
            end
            
        end

        % Button pushed function: ResetButton
        function reset(app, event)
            cla(app.outputPlot);
            cla(app.inputPlot);
            cla(app.bandPlot);
            stop(app.player);
            app.gain1.Value = 0;
            app.gain2.Value = 0;
            app.gain3.Value = 0;
            app.gain4.Value = 0;
            app.gain5.Value = 0;
            app.gain6.Value = 0;
            app.gain7.Value = 0;
            app.gain8.Value = 0;
            app.gain9.Value = 0;
            app.samplerateEditField.Value = 0;
            app.filenameEditField.Value = '';
        end

        % Button pushed function: RUNButton
        function runProgram(app, event)
             file_name = app.filenameEditField.Value;
             [app.y, app.orginal_freq] = audioread(file_name);
             %app.player = audioplayer(app.y, app.orginal_freq);
             %play(app.player);
             app.fs = app.samplerateEditField.Value;
             if app.fs == 0
                 app.fs = app.orginal_freq;
             end
             fmax = app.fs/2;
             gains = [app.gain1.Value app.gain2.Value app.gain3.Value app.gain4.Value app.gain5.Value app.gain6.Value app.gain7.Value  app.gain8.Value app.gain9.Value];
             band_gains = db2mag(gains);
             if app.IIRButton.Value  %not sure of order
                  [app.b1, app.a1] = butter(4, 170/fmax);
                  [app.b2, app.a2] = butter(4,[170/fmax 300/fmax],'bandpass');
                  [app.b3, app.a3] = butter(4,[300/fmax 610/fmax],'bandpass');
                  [app.b4, app.a4] = butter(4,[610/fmax 1005/fmax],'bandpass');
                  [app.b5, app.a5] = butter(4,[1005/fmax 3000/fmax],'bandpass');
                  [app.b6, app.a6] = butter(4,[3000/fmax 6000/fmax],'bandpass');
                  [app.b7, app.a7] = butter(4,[6000/fmax 12000/fmax],'bandpass');
                  [app.b8, app.a8] = butter(4,[12000/fmax 14000/fmax],'bandpass');
                  [app.b9, app.a9] = butter(4,[14000/fmax 20000/fmax],'bandpass');
             else
                 app.a1 = 1; app.a2 = 1; app.a3 = 1; app.a4 = 1; app.a5 = 1; app.a6 =1; app.a7 = 1; app.a8 = 1; app.a9 = 1;
                 app.b1 = fir1(40,170/fmax);
                 app.b2 = fir1(40,[170 300]/fmax);
                 app.b3 = fir1(40,[300 610]/fmax);
                 app.b4 = fir1(40,[610 1005]/fmax);
                 app.b5 = fir1(40,[1005 3000]/fmax);
                 app.b6 = fir1(40,[3000 6000]/fmax);
                 app.b7 = fir1(40,[6000 12000]/fmax);
                 app.b8 = fir1(40,[12000 14000]/fmax);
                 app.b9 = fir1(40,[14000 20000]/fmax);
             end
             app.y1 = filter(app.b1,app.a1,app.y)*band_gains(1);
             app.y2 = filter(app.b2,app.a2,app.y)*band_gains(2);
             app.y3 = filter(app.b3,app.a3,app.y)*band_gains(3);
             app.y4 = filter(app.b4,app.a4,app.y)*band_gains(4);
             app.y5 = filter(app.b5,app.a5,app.y)*band_gains(5);
             app.y6 = filter(app.b6,app.a6,app.y)*band_gains(6);
             app.y7 = filter(app.b7,app.a7,app.y)*band_gains(7);
             app.y8 = filter(app.b8,app.a8,app.y)*band_gains(8);
             app.y9 = filter(app.b9,app.a9,app.y)*band_gains(9);
             app.y_final = app.y1 + app.y2 + app.y3 + app.y4 + app.y5 + app.y6 + app.y7 + app.y8 + app.y9;
             app.player = audioplayer(app.y_final,app.fs);
             play(app.player);
            
             if app.TimeButton.Value
                 app.tx = linspace(0, length(app.y_final)/app.fs, length(app.y_final)); 
                 plot(app.outputPlot,app.tx,app.y_final);
                 t = linspace(0, length(app.y)/app.orginal_freq, length(app.y));
                 plot(app.inputPlot,t,app.y);
                 
                 
             else
                 %freq domain
                 length1 = length(app.y);
                 length2 = length(app.y_final);
                 x1 = linspace( -app.orginal_freq/2 , app.orginal_freq/2, length1);
                 x2 = linspace( -app.fs/2 , app.fs/2, length2);  
                 mag1 = abs(fftshift(fft(app.y))/app.orginal_freq);
                 mag2 = abs(fftshift(fft(app.y_final))/app.fs);
                 phase1 = angle(fftshift(fft(app.y)));
                 phase2 = angle(fftshift(fft(app.y_final)));
                 if app.magButton.Value
                     plot(app.inputPlot,x1,20*log10(mag1));
                     %ylabel(app.inputPlot,'Magnitude (dB)');
                     plot(app.outputPlot,x2,20*log10(mag2));
                    % ylabel(app.outputPlot,'Magnitude (dB)');
                 elseif app.phaseButton.Value
                     plot(app.inputPlot,x1,phase1*180/pi); 
                     plot(app.outputPlot,x2,phase2*180/pi);
                 else
                     plot(app.inputPlot,x1,20*log10(mag1),x1,phase1*180/pi);
                     plot(app.outputPlot,x2,20*log10(mag2),x2,phase2*180/pi);
                 end
              
             end
                 
             
        end

        % Value changed function: samplerateEditField
        function sampleFreq(app, event)
            value = app.samplerateEditField.Value;
            
        end

        % Button pushed function: SaveButton
        function save(app, event)
            audiowrite('audiocopy1.wav',app.y_final,app.fs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.filenameEditField.ValueChangedFcn = createCallbackFcn(app, @filePath, true);
            app.samplerateEditFieldLabel = uilabel(app.UIFigure);
            app.samplerateEditFieldLabel.Text = 'sample rate ';
            app.samplerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.samplerateEditField.ValueChangedFcn = createCallbackFcn(app, @sampleFreq, true);
            app.filterButtonGroup = uibuttongroup(app.UIFigure);
            app.filterButtonGroup.Title = 'filter';
            app.IIRButton = uiradiobutton(app.filterButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.IIRButton.Value = true;
            app.FIRButton = uiradiobutton(app.filterButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '0- 170 Hz';
            app.gain1 = uispinner(app.UIFigure);
            app.gain1.Limits = [-12 12];
            app.HzSpinner_2Label = uilabel(app.UIFigure);
            app.HzSpinner_2Label.Text = ' 170- 300 Hz';
            app.gain2 = uispinner(app.UIFigure);
            app.gain2.Limits = [-12 12];
            app.HzSpinner_3Label = uilabel(app.UIFigure);
            app.HzSpinner_3Label.Text = '300- 610 Hz';
            app.gain3 = uispinner(app.UIFigure);
            app.gain3.Limits = [-12 12];
            app.HzSpinner_4Label = uilabel(app.UIFigure);
            app.HzSpinner_4Label.Text = '610- 1005 Hz';
            app.gain4 = uispinner(app.UIFigure);
            app.gain4.Limits = [-12 12];
            app.Hz3KHzLabel = uilabel(app.UIFigure);
            app.Hz3KHzLabel.Text = ' 1005Hz-3KHz';
            app.gain5 = uispinner(app.UIFigure);
            app.gain5.Limits = [-12 12];
            app.KHzLabel = uilabel(app.UIFigure);
            app.KHzLabel.Text = ' 3- 6 KHz';
            app.gain6 = uispinner(app.UIFigure);
            app.gain6.Limits = [-12 12];
            app.KHzSpinner_2Label = uilabel(app.UIFigure);
            app.KHzSpinner_2Label.Text = '6-12 KHz';
            app.gain7 = uispinner(app.UIFigure);
            app.gain7.Limits = [-12 12];
            app.KHzSpinner_3Label = uilabel(app.UIFigure);
            app.KHzSpinner_3Label.Text = '12-14 KHz';
            app.gain8 = uispinner(app.UIFigure);
            app.gain8.Limits = [-12 12];
            app.KHzSpinner_4Label = uilabel(app.UIFigure);
            app.KHzSpinner_4Label.Text = '14-20 KHz';
            app.gain9 = uispinner(app.UIFigure);
            app.gain9.Limits = [-12 12];
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel_3 = uilabel(app.UIFigure);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.UIFigure);
            app.dBLabel_4.Text = 'dB';
            app.dBLabel_5 = uilabel(app.UIFigure);
            app.dBLabel_5.Text = 'dB';
            app.dBLabel_6 = uilabel(app.UIFigure);
            app.dBLabel_6.Text = 'dB';
            app.dBLabel_7 = uilabel(app.UIFigure);
            app.dBLabel_7.Text = 'dB';
            app.dBLabel_8 = uilabel(app.UIFigure);
            app.dBLabel_8.Text = 'dB';
            app.dBLabel_9 = uilabel(app.UIFigure);
            app.dBLabel_9.Text = 'dB';
            app.inputPlot = uiaxes(app.UIFigure);
            title(app.inputPlot, 'input sound')
            xlabel(app.inputPlot, 'X')
            ylabel(app.inputPlot, 'Y')
            app.outputPlot = uiaxes(app.UIFigure);
            title(app.outputPlot, 'output sound')
            xlabel(app.outputPlot, 'X')
            ylabel(app.outputPlot, 'Y')
            app.domainButtonGroup = uibuttongroup(app.UIFigure);
            app.domainButtonGroup.Title = 'domain';
            app.TimeButton = uiradiobutton(app.domainButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.FrequencyButton = uiradiobutton(app.domainButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @runProgram, true);
            app.RUNButton.Text = 'RUN';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetButton.Text = 'Reset';
            app.FrequencyButtonGroup = uibuttongroup(app.UIFigure);
            app.FrequencyButtonGroup.Title = 'Frequency';
            app.magButton = uiradiobutton(app.FrequencyButtonGroup);
            app.magButton.Text = 'mag';
            app.magButton.Value = true;
            app.phaseButton = uiradiobutton(app.FrequencyButtonGroup);
            app.phaseButton.Text = 'phase';
            app.bothButton = uiradiobutton(app.FrequencyButtonGroup);
            app.bothButton.Text = 'both';
            app.FiltertoplotDropDownLabel = uilabel(app.UIFigure);
            app.FiltertoplotDropDownLabel.Text = 'Filter to plot';
            app.FiltertoplotDropDown = uidropdown(app.UIFigure);
            app.FiltertoplotDropDown.Items = {'0-170 Hz', '170-300 Hz', '300-610 Hz', '610-1005 Hz', '1005-3000 Hz', '3-6 KHz', '6-12 KHz', '12-14 KHz', '14-20 KHz'};
            app.FiltertoplotDropDown.Value = '0-170 Hz';
            app.bandPlot = uiaxes(app.UIFigure);
            xlabel(app.bandPlot, 'X')
            ylabel(app.bandPlot, 'Y')
            app.ShowdetailsButton = uibutton(app.UIFigure, 'push');
            app.ShowdetailsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdetailsButtonPushed, true);
            app.ShowdetailsButton.Text = 'Show details';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @plotBand, true);
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end